classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MOTOR3Label        matlab.ui.control.Label
        MOTOR2Label        matlab.ui.control.Label
        MOTOR1Label        matlab.ui.control.Label
        CONTROLPANELPanel  matlab.ui.container.Panel
        StopButton         matlab.ui.control.Button
        DefaultButton      matlab.ui.control.Button
        HomeButton         matlab.ui.control.Button
        SendButton         matlab.ui.control.Button
        Panel_4            matlab.ui.container.Panel
        DecreaseButton_3   matlab.ui.control.Button
        IncreaseButton_3   matlab.ui.control.Button
        EditField_3        matlab.ui.control.NumericEditField
        Slider_3           matlab.ui.control.Slider
        ButtonGroup_3      matlab.ui.container.ButtonGroup
        CWButton_3         matlab.ui.control.ToggleButton
        CCWButton_3        matlab.ui.control.ToggleButton
        Panel_3            matlab.ui.container.Panel
        DecreaseButton_2   matlab.ui.control.Button
        IncreaseButton_2   matlab.ui.control.Button
        EditField_2        matlab.ui.control.NumericEditField
        Slider_2           matlab.ui.control.Slider
        ButtonGroup_2      matlab.ui.container.ButtonGroup
        CWButton_2         matlab.ui.control.ToggleButton
        CCWButton_2        matlab.ui.control.ToggleButton
        Panel              matlab.ui.container.Panel
        ListBox            matlab.ui.control.ListBox
        OPENButton         matlab.ui.control.Button
        REFRESHButton      matlab.ui.control.Button
        CLOSEButton        matlab.ui.control.Button
        Panel_2            matlab.ui.container.Panel
        DecreaseButton     matlab.ui.control.Button
        IncreaseButton     matlab.ui.control.Button
        EditField          matlab.ui.control.NumericEditField
        Slider             matlab.ui.control.Slider
        ButtonGroup        matlab.ui.container.ButtonGroup
        CWButton           matlab.ui.control.ToggleButton
        CCWButton          matlab.ui.control.ToggleButton
        MOTORANGLEPanel    matlab.ui.container.Panel
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        motor1_angle       matlab.ui.control.NumericEditField
        motor2_angle       matlab.ui.control.NumericEditField
        motor3_angle       matlab.ui.control.NumericEditField
    end

    properties (Access = private)
         serialObj % Description
    end

    methods (Access = private)
        
        function uartCallback(app, src, ~)
        
            % Đọc dữ liệu từ serial port
            data = read(src, src.NumBytesAvailable, "uint8"); % Đọc số byte sẵn có
            % Chuyển dữ liệu thành chuỗi (nếu cần)
            dataStr = char(data);
            % Hiển thị dữ liệu lên giao diện (ví dụ: ListBox)
            
            values = split(extractBefore(dataStr, '#'), ':');
            
            a = str2double(values(1));
            b = str2double(values(2));
            c = str2double(values(3));
            app.motor1_angle.Value = a;
            app.motor2_angle.Value = b;
            app.motor3_angle.Value = c;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            comPorts = serialportlist("all");

            % Cập nhật listbox với danh sách COM
            app.ListBox.Items = comPorts;
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            try
            % Lấy tên COM từ ListBox
            selectedPort = app.ListBox.Value;

            % Nếu đã mở rồi thì đóng lại trước
            if ~isempty(app.serialObj) && isvalid(app.serialObj)
                delete(app.serialObj);
            end

            % Mở cổng COM với tốc độ baud là 9600 (hoặc chỉnh theo thiết bị bạn)
            app.serialObj = serialport(selectedPort, 115200);

            % Hiển thị thông báo hoặc cập nhật trạng thái
            uialert(app.UIFigure, ['Đã mở ' selectedPort], 'Thành công');

            catch ME
                uialert(app.UIFigure, ['Lỗi mở cổng COM: ' ME.message], 'Lỗi');
                return;
            end
            configureCallback(app.serialObj, "byte", 1, @(src, evt) uartCallback(app, src, evt));  
            app.CLOSEButton.Enable = "on";
            app.OPENButton.Enable = "off";
            app.REFRESHButton.Enable = "off";
            app.Panel_2.Visible = "on";
            app.Panel_3.Visible = "on";
            app.Panel_4.Visible = "on";
            app.MOTOR1Label.Visible = "on";
            app.MOTOR2Label.Visible = "on";
            app.MOTOR3Label.Visible = "on";
            app.CONTROLPANELPanel.Visible = "on";
            app.MOTORANGLEPanel.Visible = "on";
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            if ~isempty(app.serialObj) && isvalid(app.serialObj)
                delete(app.serialObj);
            app.serialObj = [];
                uialert(app.UIFigure, 'Đã đóng cổng COM', 'Thông báo');
            else
                uialert(app.UIFigure, 'Không có cổng nào đang mở', 'Thông báo');
            end
            app.CLOSEButton.Enable = "off";
            app.OPENButton.Enable = "on";
            app.REFRESHButton.Enable = "on";
            app.Panel_2.Visible = "off";
            app.Panel_3.Visible = "off";
            app.Panel_4.Visible = "off";
            app.MOTOR1Label.Visible = "off";
            app.MOTOR2Label.Visible = "off";
            app.MOTOR3Label.Visible = "off";
            app.CONTROLPANELPanel.Visible = "off";
            app.MOTORANGLEPanel.Visible = "off";
        end

        % Button pushed function: REFRESHButton
        function REFRESHButtonPushed(app, event)
            % Lấy danh sách các cổng COM
            comPorts = serialportlist("all");

            % Cập nhật listbox với danh sách COM
            app.ListBox.Items = comPorts;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             if ~isempty(app.serialObj) && isvalid(app.serialObj)
                delete(app.serialObj);
                 app.serialObj = [];  
             end
            delete(app)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            roundvalue = round(value);
            app.EditField.Value = roundvalue;
        end

        % Button pushed function: IncreaseButton
        function IncreaseButtonPushed(app, event)
            currentVal = app.Slider.Value;
            newVal = min(currentVal + 1, 180);
            app.Slider.Value = round(newVal);
            app.EditField.Value = round(newVal);
        end

        % Button pushed function: DecreaseButton
        function DecreaseButtonPushed(app, event)
            currentVal = app.Slider.Value;
            newVal = max(currentVal - 1, 0);
            app.Slider.Value = round(newVal);
            app.EditField.Value = round(newVal);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            roundvalue = round(changingValue);
            app.EditField_2.Value = roundvalue;
        end

        % Button pushed function: IncreaseButton_2
        function IncreaseButton_2Pushed(app, event)
            currentVal = app.Slider_2.Value;
            newVal = min(currentVal + 1, 180);
            app.Slider_2.Value = round(newVal);
            app.EditField_2.Value = round(newVal);
        end

        % Button pushed function: DecreaseButton_2
        function DecreaseButton_2Pushed(app, event)
            currentVal = app.Slider_2.Value;
            newVal = max(currentVal - 1, 0);
            app.Slider_2.Value = round(newVal);
            app.EditField_2.Value = round(newVal);
        end

        % Button pushed function: SendButton
        function SendButtonPushed2(app, event)
        motor1_angle = num2str(app.EditField.Value);
        motor2_angle = num2str(app.EditField_2.Value);
        motor3_angle = num2str(app.EditField_3.Value);

        if strcmp(app.ButtonGroup.SelectedObject.Text, 'CCW')
            motor1_direction = 'CC';
        else
            motor1_direction = 'CW';
        end

        if strcmp(app.ButtonGroup_2.SelectedObject.Text, 'CCW')
            motor2_direction = 'CC';
        else
            motor2_direction = 'CW';
        end

        if strcmp(app.ButtonGroup_3.SelectedObject.Text, 'CCW')
            motor3_direction = 'CC';
        else
            motor3_direction = 'CW';
        end

% Ghép chuỗi hoàn chỉnh
        data_to_send = ['1:' motor1_angle motor1_direction '2:' motor2_angle motor2_direction '3:' motor3_angle motor3_direction ';'];

% Gửi qua cổng COM
        write(app.serialObj, data_to_send, "char");
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Slider.Value = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Slider_2.Value = value;
        end

        % Button pushed function: IncreaseButton_3
        function IncreaseButton_3Pushed(app, event)
            currentVal = app.Slider_3.Value;
            newVal = min(currentVal + 1, 180);
            app.Slider_3.Value = round(newVal);
            app.EditField_3.Value = round(newVal);
        end

        % Button pushed function: DecreaseButton_3
        function DecreaseButton_3Pushed(app, event)
            currentVal = app.Slider_3.Value;
            newVal = min(currentVal - 1, 180);
            app.Slider_3.Value = round(newVal);
            app.EditField_3.Value = round(newVal);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            roundValue = round(changingValue);
            app.EditField_3.Value = roundValue;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Slider_3.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MOTORANGLEPanel = uipanel(app.UIFigure);
            app.MOTORANGLEPanel.Title = 'MOTOR ANGLE';
            app.MOTORANGLEPanel.Visible = 'off';
            app.motor3_angle = uieditfield(app.MOTORANGLEPanel, 'numeric');
            app.motor3_angle.ValueDisplayFormat = '%.0f';
            app.motor2_angle = uieditfield(app.MOTORANGLEPanel, 'numeric');
            app.motor1_angle = uieditfield(app.MOTORANGLEPanel, 'numeric');
            app.Label = uilabel(app.MOTORANGLEPanel);
            app.Label.Text = '1:';
            app.Label_2 = uilabel(app.MOTORANGLEPanel);
            app.Label_2.Text = '2:';
            app.Label_3 = uilabel(app.MOTORANGLEPanel);
            app.Label_3.Text = '3:';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.CCWButton = uitogglebutton(app.ButtonGroup);
            app.CCWButton.Text = 'CCW';
            app.CCWButton.Value = true;
            app.CWButton = uitogglebutton(app.ButtonGroup);
            app.CWButton.Text = 'CW';
            app.Slider = uislider(app.Panel_2);
            app.Slider.Limits = [0 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.IncreaseButton = uibutton(app.Panel_2, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButtonPushed, true);
            app.IncreaseButton.Text = 'Increase';
            app.DecreaseButton = uibutton(app.Panel_2, 'push');
            app.DecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButtonPushed, true);
            app.DecreaseButton.Text = 'Decrease';
            app.Panel = uipanel(app.UIFigure);
            app.CLOSEButton = uibutton(app.Panel, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Enable = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            app.REFRESHButton = uibutton(app.Panel, 'push');
            app.REFRESHButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHButtonPushed, true);
            app.REFRESHButton.Text = 'REFRESH';
            app.OPENButton = uibutton(app.Panel, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.ListBox = uilistbox(app.Panel);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_3);
            app.CCWButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.CCWButton_2.Text = 'CCW';
            app.CCWButton_2.Value = true;
            app.CWButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.CWButton_2.Text = 'CW';
            app.Slider_2 = uislider(app.Panel_3);
            app.Slider_2.Limits = [0 180];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.IncreaseButton_2 = uibutton(app.Panel_3, 'push');
            app.IncreaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButton_2Pushed, true);
            app.IncreaseButton_2.Text = 'Increase';
            app.DecreaseButton_2 = uibutton(app.Panel_3, 'push');
            app.DecreaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButton_2Pushed, true);
            app.DecreaseButton_2.Text = 'Decrease';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Visible = 'off';
            app.ButtonGroup_3 = uibuttongroup(app.Panel_4);
            app.CCWButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.CCWButton_3.Text = 'CCW';
            app.CCWButton_3.Value = true;
            app.CWButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.CWButton_3.Text = 'CW';
            app.Slider_3 = uislider(app.Panel_4);
            app.Slider_3.Limits = [0 180];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.EditField_3 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.IncreaseButton_3 = uibutton(app.Panel_4, 'push');
            app.IncreaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButton_3Pushed, true);
            app.IncreaseButton_3.Text = 'Increase';
            app.DecreaseButton_3 = uibutton(app.Panel_4, 'push');
            app.DecreaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButton_3Pushed, true);
            app.DecreaseButton_3.Text = 'Decrease';
            app.CONTROLPANELPanel = uipanel(app.UIFigure);
            app.CONTROLPANELPanel.Title = 'CONTROL PANEL';
            app.CONTROLPANELPanel.Visible = 'off';
            app.SendButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed2, true);
            app.SendButton.Text = 'Send';
            app.HomeButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.HomeButton.Text = 'Home';
            app.DefaultButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.DefaultButton.Text = 'Default';
            app.StopButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.MOTOR1Label = uilabel(app.UIFigure);
            app.MOTOR1Label.Visible = 'off';
            app.MOTOR1Label.Text = 'MOTOR 1';
            app.MOTOR2Label = uilabel(app.UIFigure);
            app.MOTOR2Label.Visible = 'off';
            app.MOTOR2Label.Text = 'MOTOR 2';
            app.MOTOR3Label = uilabel(app.UIFigure);
            app.MOTOR3Label.Visible = 'off';
            app.MOTOR3Label.Text = 'MOTOR 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end